classdef LoggingUSB6221 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ResultfilePanel            matlab.ui.container.Panel
        DataDirIndicator           matlab.ui.control.Lamp
        Label                      matlab.ui.control.Label
        Explorer                   matlab.ui.control.Button
        DataDir                    matlab.ui.control.Label
        SelectDataDirButton        matlab.ui.control.Button
        LogStatusText              matlab.ui.control.Label
        Elapsedtime                matlab.ui.control.Label
        VerticalForce              matlab.ui.control.LinearGauge
        VerticalForcekgGaugeLabel  matlab.ui.control.Label
        TimewindowEditField        matlab.ui.control.NumericEditField
        EditFieldLabel             matlab.ui.control.Label
        StopButton                 matlab.ui.control.Button
        StartButton                matlab.ui.control.Button
        AxesMoments                matlab.ui.control.UIAxes
        AxesForces                 matlab.ui.control.UIAxes
        LiveAxes                   matlab.ui.control.UIAxes
    end


    properties (Access = public)
        DaqSession
        lh1
        lh2
        Time
        Data
        DAQ
        DAQSubsystemTypes = {'AnalogInput','AudioInput'};  % DAQ subsystem types supported by the app
        DAQMeasurementTypes = {'Voltage','IEPE','Audio'};  % DAQ input measurement types supported by the app
        FIFOMaxSize = 5000    % Maximum allowed FIFO buffer size for DataFIFOBuffer and TimestampsFIFOBuffer
        TempFilename          % Temporary binary file name, acquired data is logged to this file during acquisition
        TempFile              % Handle of opened binary file, acquired data is logged to this file during acquisition
        Filename = 'daqdata.mat' % Default MAT file name at app start
        Filepath = pwd        % Default folder for saving the MAT file at app start
        LivePlotLines
        Device
        devices
        TriggerTime
        LiveTimeWindowSeconds = 5
        appName = 'NILogger'
        ChanConfig
        DataFIFOBuffers
        TimeStampFIFOBuffers
        LivePlotCount = 0
        state
        StartTimeStamp
    end

    methods (Access = public)

        function InitaliseFromPreferences(app)
        app.devices = daqListSupportedDevices(app, app.DAQSubsystemTypes, app.DAQMeasurementTypes);
        app.ChanConfig = load('ChanConfig.mat');
        app.ChanConfig = app.ChanConfig.cc;

        fn = fieldnames(app);

        for i=1:numel(fn)
            for j=1:height(app.ChanConfig)
                if strcmpi(fn{i}, app.ChanConfig.Axes{j})
                    app.ChanConfig.AxesHandle{j} = app.(fn{i});
                end
            end
        end

        %ylim(app.AxesForces, [0 1200]);
        %ylim(app.AxesMoments, [-5000 5000])

        end

        function deviceinfo = daqListSupportedDevices(app, subsystemTypes, measurementTypes)
        %daqListSupportedDevices Get connected devices that support the specified subsystem and measurement types

        % Detect all connected devices
        devices = daqlist;
        deviceinfo = devices.DeviceInfo;

        % Keep a subset of devices which have the specified subystem and measurement types
        deviceinfo = daqFilterDevicesBySubsystem(app, deviceinfo, subsystemTypes);
        deviceinfo = daqFilterDevicesByMeasurement(app, deviceinfo, measurementTypes);

        end

        function SetupAcq(app)

        setAppViewState(app, 'startup');
        
        
        r=daqlist;
        
        
        app.Device = r.DeviceID;  %Assuming only 1 device
        app.DAQ = daq('ni');

        InitaliseFromPreferences(app);

        app.DAQ.Rate = 1000;

        cc = app.ChanConfig;

        for i=1:height(app.ChanConfig)
            addinput(app.DAQ, app.Device, [cc.ChanType{i} num2str(cc.ChanNumber{i}-1)], cc.Fysical{i});
            app.DAQ.Channels(i).TerminalConfig =  cc.Ended{i};
        end

        app.DAQ.ScansAvailableFcn = @(src,event) scansAvailable_Callback(app, src, event);

        hold(app.LiveAxes,'on');
        hold(app.AxesForces,'on');
        hold(app.AxesMoments,'on');

        for i = 1:height(app.ChanConfig)
            if app.ChanConfig.Buffer(i)>0
                app.LivePlotCount = app.LivePlotCount+1;
                app.LivePlotLines{app.LivePlotCount} = plot(app.ChanConfig.AxesHandle{i}, NaN, NaN);
            end
        end

        setAppViewState(app, 'awaitlogging');

        end

        function scansAvailable_Callback(app, src, ~)
        %scansAvailable_Callback Executes on DAQ object ScansAvailable event
        %  This callback function gets executed periodically as more data is acquired.
        %  For a smooth live plot update, it stores the latest N seconds
        %  (specified time window) of acquired data and relative timestamps in FIFO
        %  buffers. A live plot is updated with the data in the FIFO buffer.
        %  If data logging option is selected in the UI, it also writes data to a
        %  binary file.

        if ~isvalid(app)
            return
        end

        [data,timestamps,triggertime] = read(src, src.ScansAvailableFcnCount, 'OutputFormat','Matrix');

        latestdata = [timestamps, data]' ;
        
        for i=1:numel(height(app.ChanConfig))
            data(:,i) = (data(:,i)+app.ChanConfig.Offset(i)) * app.ChanConfig.Scaling(i);
        end

        app.VerticalForce.Value = mean(data(:,1)) *10;

        fwrite(app.TempFile, latestdata, 'double');
        if timestamps(1)==0
            app.TriggerTime = triggertime;
        end

        % Store continuous acquisition data in FIFO data buffers
        %buffersize = round(app.DAQ.Rate * app.TimewindowEditField.Value) + 1;


        for i=1:height(app.ChanConfig)
            if app.ChanConfig.Buffer(i) > 0
                app.TimeStampFIFOBuffers{i} = storeDataInFIFO(app, app.TimeStampFIFOBuffers{i}, app.ChanConfig.Buffer(i), timestamps);
                app.DataFIFOBuffers{i} = storeDataInFIFO(app, app.DataFIFOBuffers{i}, app.ChanConfig.Buffer(i), data(:,i));
            end
        end

        % Update plot data
        counter = 0;
        for i=1:height(app.ChanConfig)
            if app.ChanConfig.Buffer(i) > 0 
                counter = counter + 1;
                set(app.LivePlotLines{counter}, 'XData', app.TimeStampFIFOBuffers{i}, 'YData', app.DataFIFOBuffers{i});
                ffBuff = app.TimeStampFIFOBuffers{counter};
                xlim(app.ChanConfig.AxesHandle{i}, [ffBuff(1), ffBuff(end)])
            end
        end

        if numel(app.TimeStampFIFOBuffers{1}) > 1
            tsBuff = app.TimeStampFIFOBuffers{1};
            xlim(app.LiveAxes, [tsBuff(1), tsBuff(end)])
        end

        app.Elapsedtime.Text = [num2str((now()-app.StartTimeStamp)*86400,4) 's'];

        end

        function setAppViewState(app, state)
        switch state
            case 'startup'
                app.StartButton.Enable = false;
                app.StopButton.Enable = false;
                return
            case 'awaitlogging'
                app.StartButton.Enable = true;
                app.StopButton.Enable = false;
                return
            case 'measuring'
                app.StartButton.Enable = false;
                app.StopButton.Enable = true;
                return
        end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, arg1)
        SetupAcq(app);

        dataDir = getpref(app.appName,'dataDir');
        if ~isempty(dataDir) && ischar(dataDir)
            app.DataDir.Text = dataDir;
        end

        if 7==exist(dataDir,'dir')
            app.DataDirIndicator.Color = [0 1 0];
        else
            app.DataDirIndicator.Color = [1 0 0];
        end


        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
        setAppViewState(app, 'measuring');

        % Create and open temporary binary file to log data to disk
        app.TempFilename = tempname;
        [~,name,~] = fileparts(app.TempFilename);
        app.TempFilename = [app.DataDir.Text filesep name];

        app.TempFile = fopen(app.TempFilename, 'w');

        for i = 1:height(app.ChanConfig)
            app.TimeStampFIFOBuffers{i} = [];
            app.DataFIFOBuffers{i} = [];
        end

        try
            start(app.DAQ,'continuous');
            app.StartTimeStamp = now();
        catch exception
            % In case of error show it and revert the change
            uialert(app.LiveDataAcquisitionUIFigure, exception.message, 'Start error');
            setAppViewState(app, 'configuration');
        end

        % Clear Log status text
        %app.LogStatusText.Text = '';

        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)

        setAppViewState(app, 'filesave');
        stop(app.DAQ);


        % Log data to file switch is on
        % Save logged data to MAT file (unless the user clicks Cancel in the "Save As" dialog)

        % Close temporary binary file
        fclose(app.TempFile);


        % Gather metadata in preparation for saving to MAT file
        % Store relevant Daq device info

        deviceInfo.Vendor = 'NI';
        metadata.DeviceInfo = deviceInfo;
        metadata.Channel = '1';
        metadata.Rate = '1000';
        metadata.TriggerTime = datetime(now(), 'ConvertFrom', 'datenum', 'TimeZone', 'local');

        t = datetime('now', 'Format','yyyy_MM_dd,HH_mm');
        fname = datestr(t) ;
        fname=strrep(fname,':','_');
        app.Filename = [fname '.mat'];
        app.Filepath = app.DataDir.Text;

        % Convert data from binary file to MAT file
        matFilepath = fullfile(app.Filepath, app.Filename);
        app.LogStatusText.Text = 'Saving data to MAT file is in progress...';
        drawnow

        numColumns = height(app.ChanConfig);
        binFile2MAT(app, app.TempFilename, matFilepath, numColumns, metadata);
        app.LogStatusText.Text = sprintf('Saving data to ''%s'' file has completed.', app.Filename);

        % Enable DAQ device, channel properties, and start acquisition UI components
        setAppViewState(app, 'configuration');

        end

        % Button pushed function: SelectDataDirButton
        function SelectDataDirButtonPushed(app, event)
        selpath = uigetdir;
        setpref(app.appName,'dataDir', selpath);
        app.DataDir.Text = selpath;
        end

        % Button pushed function: Explorer
        function ExplorerButtonPushed(app, event)
        winopen(app.DataDir.Text);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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        app.UIFigure = uifigure('Visible', 'off');
        app.UIFigure.Name = 'UI Figure';
        app.LiveAxes = uiaxes(app.UIFigure);
        title(app.LiveAxes, 'LiveAxes')
        xlabel(app.LiveAxes, 'X')
        ylabel(app.LiveAxes, 'Y')
        zlabel(app.LiveAxes, 'Z')
        app.AxesForces = uiaxes(app.UIFigure);
        title(app.AxesForces, 'Forces')
        xlabel(app.AxesForces, 'X')
        ylabel(app.AxesForces, 'Y')
        zlabel(app.AxesForces, 'Z')
        app.AxesMoments = uiaxes(app.UIFigure);
        title(app.AxesMoments, 'Moments')
        xlabel(app.AxesMoments, 'X')
        ylabel(app.AxesMoments, 'Y')
        zlabel(app.AxesMoments, 'Z')
        app.StartButton = uibutton(app.UIFigure, 'push');
        app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
        app.StartButton.Text = 'Start';
        app.StopButton = uibutton(app.UIFigure, 'push');
        app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
        app.StopButton.Text = 'Stop';
        app.EditFieldLabel = uilabel(app.UIFigure);
        app.EditFieldLabel.Text = 'Edit Field';
        app.TimewindowEditField = uieditfield(app.UIFigure, 'numeric');
        app.TimewindowEditField.Value = 1000;
        app.VerticalForcekgGaugeLabel = uilabel(app.UIFigure);
        app.VerticalForcekgGaugeLabel.Text = 'Vertical Force [kg]';
        app.VerticalForce = uigauge(app.UIFigure, 'linear');
        app.Elapsedtime = uilabel(app.UIFigure);
        app.Elapsedtime.Text = '0';
        app.ResultfilePanel = uipanel(app.UIFigure);
        app.ResultfilePanel.Title = 'Result file';
        app.LogStatusText = uilabel(app.ResultfilePanel);
        app.LogStatusText.Text = '...';
        app.SelectDataDirButton = uibutton(app.ResultfilePanel, 'push');
        app.SelectDataDirButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDataDirButtonPushed, true);
        app.SelectDataDirButton.Text = 'Select Data Dir';
        app.DataDir = uilabel(app.ResultfilePanel);
        app.Explorer = uibutton(app.ResultfilePanel, 'push');
        app.Explorer.ButtonPushedFcn = createCallbackFcn(app, @ExplorerButtonPushed, true);
        app.Explorer.Text = 'Open in explorer';
        app.Label = uilabel(app.ResultfilePanel);
        app.Label.Text = '';
        app.DataDirIndicator = uilamp(app.ResultfilePanel);
        % Show the figure after all components are created
        app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LoggingUSB6221(varargin)

        % Create UIFigure and components
        createComponents(app)

        % Register the app with App Designer
        registerApp(app, app.UIFigure)

        % Execute the startup function
        runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

        if nargout == 0
            clear app
        end
        end

        % Code that executes before app deletion
        function delete(app)

        % Delete UIFigure when app is deleted
        delete(app.UIFigure)
        end
    end
end